classdef HUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        O2ConsumptionLamp       matlab.ui.control.Lamp
        O2ConsumptionLampLabel  matlab.ui.control.Label
        ROIDist                 matlab.ui.control.Label
        LEMDist                 matlab.ui.control.Label
        CogLoadLamp             matlab.ui.control.Lamp
        CogLoadLampLabel        matlab.ui.control.Label
        HRLamp                  matlab.ui.control.Lamp
        HRLamp_2Label           matlab.ui.control.Label
        RRLamp                  matlab.ui.control.Lamp
        RRLampLabel             matlab.ui.control.Label
        TaskInstructions        matlab.ui.control.Label
        BatteryValue            matlab.ui.control.Label
        BattLabel               matlab.ui.control.Label
        PressureValue           matlab.ui.control.Label
        SuitLabel               matlab.ui.control.Label
        OxygenLevelTitle        matlab.ui.control.Label
        NominalLabel            matlab.ui.control.Label
        CO2Label                matlab.ui.control.Label
        PlaySimulationButton    matlab.ui.control.Button
        MiniMap                 matlab.ui.control.UIAxes
        CompassAxes             matlab.ui.control.UIAxes
        UIOxygenAxes            matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
            app.UIOxygenAxes.Title.Color = 'w';  % Change title font color to white
            % since apparently this isn't an option in design viewer, so we
            % have to manually do it  
            
            % manually changing some compass features
            app.CompassAxes.XColor = 'none';
            app.CompassAxes.YColor = 'none';
            app.CompassAxes.Color = 'none'; % Transparent background
            app.CompassAxes.Box = 'off'; % Remove box outline
            axis(app.CompassAxes, 'equal');
            hold(app.CompassAxes, 'on');
            
            % static compass rose
            theta = linspace(0, 2*pi, 100);
            x = cos(theta);
            y = sin(theta);
            plot(app.CompassAxes, x, y, 'w'); % circle outline for compass
            

            text(app.CompassAxes, 0, 1.2, 'N', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 1.2, 0, 'E', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 0, -1.2, 'S', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, -1.2, 0, 'W', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');


        end

        % Button pushed function: PlaySimulationButton
        function PlaySimulationButtonPushed(app, event)
            % When play simulation button is pushed, read in HUDFootage and
            % display it within our figure (UIAxes)
            filename = 'data/HUDFootage.mp4';
            v = VideoReader(filename);
            frame = readFrame(v);
            im_frame = imshow(frame, 'Parent', app.UIAxes);

            % Read in minimap and display it within app.MiniMap
            filenameMap = 'data/minimap.mp4';
            vMap = VideoReader(filenameMap);
            frameMap = readFrame(vMap);
            im_frameMap = imshow(frameMap, 'Parent', app.MiniMap);

            % load display data
            displayData = load('data/displayDataAllAlerts.csv');
            oxygenData = displayData(2,:)'; % column vector made from row 2 of csv
            co2Data = displayData(3,:)'; % column vector made from row 3 of csv
            pressureData = displayData(4,:)';
            batteryData = displayData(5,:)';
            heartData = displayData(6,:)';
            respData = displayData(7,:)';
            o2consumeData = displayData(8,:)';
            cogloadData = displayData(9,:)';
            compassData = displayData(10,:)';
            lemDistData = displayData(11,:)';
            roiDistData = displayData(12,:)';
            %taskData = load('data/evaInstructions.csv');

            % load event data
            eventData = load('data/eventData.csv');
            % set triggers corresponding to rows 
            hypoxiaTrigger = eventData(2,:)'; % column vector made from row 2 of csv
            workloadTrigger = eventData(3,:)'; 
            batteryTrigger = eventData(4,:)';

            % Initialize dynamic heading pointer
            headingAngle = 180; % Initial heading, update dynamically. Initially west
            xPointer = [0, cosd(headingAngle)];
            yPointer = [0, sind(headingAngle)];
            compassPointer = plot(app.CompassAxes, xPointer, yPointer, 'r', 'LineWidth', 2);

            % Set font sizes
            %nominalFont = 12;
            %offnominalFont = 15;

            % Prepare oxygen bar
            hold(app.UIOxygenAxes, 'on');
            oxygenBar = rectangle(app.UIOxygenAxes,...
                'Position', [0, 0, 1, 0],...
                'FaceColor', 'g',...
                'EdgeColor','none');
            ylim(app.UIOxygenAxes, [0, 1]); % Normalize to percentage (0-1)
            hold(app.UIOxygenAxes, 'off');
            
            % loop through entire video length
            frameIdx = 1;
            % Set initial alert flag equal to 0 (ie no alerts)
            O2alertFlag = 0;
            battAlertFlag = 0;
            workloadAlertFlag = 0;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
% LOOP THROUGH VIDEO
            while hasFrame(v) && frameIdx <= length(oxygenData)
                im_frame.CData = readFrame(v);
                im_frameMap.CData = readFrame(vMap);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
% EVENT TRIGGERS
                % if the alert boxes already exist, delete them
                if O2alertFlag == 1
                    % This will prevent us from re-drawing the same box on
                    % top of previous alert boxes and also make sure that
                    % we get rid of the alert after it's gone
                    % delete(alertRect);
                    delete(alertText);
                elseif  battAlertFlag == 1 || workloadAlertFlag == 1
                    delete(alertText);
                    delete(alert2Text);
                end

                % Workload trigger
                if hypoxiaTrigger(frameIdx) == 0 && batteryTrigger(frameIdx) == 0 && workloadTrigger(frameIdx) == 1
                    xCenter = 1000; % Center alert box in middle of screen
                    yCenter = 50; % Center alert box in middle of screen
                    alertText = text(app.UIAxes,xCenter,yCenter,"[WORKLOAD WARNING]",...
                        'HorizontalAlignment','center',...
                        'FontSize',14,...
                        'FontWeight','bold',...
                        'FontName', 'Consolas',...
                        'Color','w',...
                        'Visible', 'on');
                    alert2Text = text(app.UIAxes,xCenter,yCenter + 50,"Slow down",...
                        'HorizontalAlignment','center',...
                        'FontSize',12,...
                        'FontWeight','normal',...
                        'FontName', 'Consolas',...
                        'Color','w',...
                        'Visible', 'on');
                    % Make the alert text blink
                        if mod(frameIdx,5) == 0 || mod(frameIdx+1,5) ==0 || mod(frameIdx+2,5) ==0
                            alertText.Visible = 'on';
                            alert2Text.Visible = 'on';
                        else
                            alertText.Visible = 'off';
                            alert2Text.Visible = 'on';
                        end
                    % adaptive interface
                    % make extraneous data invisible 
                    app.TaskInstructions.Visible = 'off';
                    app.LEMDist.Visible = 'off';
                    app.ROIDist.Visible = 'off';

                    % Let us know in next run-through of the loop that we
                    % had an alert last loop
                    workloadAlertFlag = 1;

                elseif hypoxiaTrigger(frameIdx) == 0 && batteryTrigger(frameIdx) == 0 && workloadTrigger(frameIdx) == 0
                    % Let us know in next run-through of the loop that we
                    % had NO alert last loop
                    workloadAlertFlag = 0;
                   
                    % return interface to nominal
                    app.LEMDist.Visible = 'on';
                    app.ROIDist.Visible = 'on';
                    app.TaskInstructions.Visible = 'on';


                end
        
                
                % Suit leak trigger
                currentLevel = oxygenData(frameIdx)/100; % Get current oxygen level
                if hypoxiaTrigger(frameIdx) == 1 && batteryTrigger(frameIdx) == 0 && workloadTrigger(frameIdx) == 0
                  % allowing for transparency features:
                  set(app.UIFigure, 'Renderer', 'OpenGL');
                    % ^ no longer doing this 

                    % rectangle alert in middle top of screen
                    %width = 300; % width:
                    %height = 100; % height
                    xCenter = 1000; % Center alert box in middle of screen
                    yCenter = 50; % Center alert box in middle of screen
                    % xLeft = xCenter - width/5;
                    % yBottom = yCenter - height/5;
                    % alertRect = rectangle(app.UIAxes,...
                    %     'Position', [xLeft, yBottom, width, height],...
                    %     'FaceColor', [1, 0, 0, 0.5],...
                    %     'EdgeColor', [1, 0, 0, 0.5],...
                    %     'Curvature',0.2);
                    alertText = text(app.UIAxes,xCenter,yCenter,"[HYPOXIA ALERT]",...
                        'HorizontalAlignment','center',...
                        'FontSize',14,...
                        'FontWeight','bold',...
                        'FontName', 'Consolas',...
                        'Color','w',...
                        'Visible', 'on');
                    % Make the alert text blink
                        if mod(frameIdx,5) == 0 || mod(frameIdx+1,5) ==0 || mod(frameIdx+2,5) ==0
                            alertText.Visible = 'on';
                        else
                            alertText.Visible = 'off';
                        end
                    
                    % adaptive interface
                    % increase the size (1.5 x) of the oxygen bar 
                    app.UIOxygenAxes.Position = [18 160 1.5*51 1.5*122];
                    app.OxygenLevelTitle.Position = [16 342 88 28];
                    ylim(app.UIOxygenAxes, [0, 2]);
                    app.UIOxygenAxes.YTick = [0 1 2];
                    oxygenBar.Position = [0 0 1 currentLevel*2.5];

                    % adaptive interface
                    % make extraneous data invisible 
                    app.LEMDist.Visible = 'off';
                    app.ROIDist.Visible = 'off';
                    app.TaskInstructions.Visible = 'off';

                    
                    % Let us know in next run-through of the loop that we
                    % had an alert last loop
                    O2alertFlag = 1;
                    

                elseif hypoxiaTrigger(frameIdx) == 0 && batteryTrigger(frameIdx) == 0 && workloadTrigger(frameIdx) == 0
                    % Let us know in next run-through of the loop that we
                    % had NO alert last loop
                    O2alertFlag = 0;
                    % reset the o2 bar back to normal size
                    app.UIOxygenAxes.Position = [18 160 51 122];
                    app.OxygenLevelTitle.Position = [16 281 88 28];
                    app.UIOxygenAxes.YTick = [0 0.5 1];
                    ylim(app.UIOxygenAxes, [0, 1]);
                    app.UIOxygenAxes.YTick = [0 0.5 1];
                    oxygenBar.Position = [0 0 1 currentLevel];
                    app.LEMDist.Visible = 'on';
                    app.ROIDist.Visible = 'on';
                    app.TaskInstructions.Visible = 'on';
                    % re-set the font sizes back to normal
                    %app.OxygenLevelTitle.FontSize = nominalFont;
                    %app.NominalLabel.FontSize = nominalFont;
                    % Set CO2 scrubber to invisible
                    %app.CO2Label.Visible = "on";
                    %app.NominalLabel.Visible = "on";
                end

                % Low battery trigger
                if batteryTrigger(frameIdx) == 1 && hypoxiaTrigger(frameIdx) == 0 && workloadTrigger(frameIdx) == 0
                    xCenter = 1000; % Center alert box in middle of screen
                    yCenter = 50; % Center alert box in middle of screen

                    alertText = text(app.UIAxes,xCenter,yCenter,"[LOW BATTERY]",...
                        'HorizontalAlignment','center',...
                        'FontSize',14,...
                        'FontWeight','bold',...
                        'FontName', 'Consolas',...
                        'Color','w',...
                        'Visible', 'on');
                    alert2Text = text(app.UIAxes,xCenter,yCenter + 50,"Return to LEM now",...
                        'HorizontalAlignment','center',...
                        'FontSize',12,...
                        'FontWeight','normal',...
                        'FontName', 'Consolas',...
                        'Color','w',...
                        'Visible', 'on');
                    % Make the alert text blink
                        if mod(frameIdx,5) == 0 || mod(frameIdx+1,5) ==0 || mod(frameIdx+2,5) ==0
                            alertText.Visible = 'on';
                            alert2Text.Visible = 'on';
                        else
                            alertText.Visible = 'off';
                            alert2Text.Visible = 'on';
                        end


                    % adaptive interface
                    % make extraneous data invisible 
                    app.TaskInstructions.Visible = 'off';
     
                    % adaptive interface
                    % Original dimensions
                    oldWidth = 121;
                    oldHeight = 112;
                    % New dimensions (1.5x scale)
                    scale = 1.5;
                    newWidth = oldWidth * scale;
                    newHeight = oldHeight * scale;
                    % Growth
                    growthX = newWidth - oldWidth;
                    growthY = newHeight - oldHeight;
                    % Extra vertical adjustment for better positioning
                    extraOffset = 10;
                    % Update MiniMap position
                    app.MiniMap.Position = [504 - growthX, 323 - growthY - extraOffset, newWidth, newHeight];
                    % Update CompassAxes position
                    app.CompassAxes.Position = [460- growthX+10, 382 - growthY  - extraOffset, 42, 42];
                    app.ROIDist.Position = [410- growthX+10 342-growthY  - extraOffset 98 31];
                    app.LEMDist.Position = [410- growthX+10 356-growthY  - extraOffset 95 31];
                    
                    
                    % Let us know in next run-through of the loop that we
                    % had an alert last loop
                    battAlertFlag = 1;

                elseif hypoxiaTrigger(frameIdx) == 0 && batteryTrigger(frameIdx) == 0 && workloadTrigger(frameIdx) == 0
                    % Let us know in next run-through of the loop that we
                    % had NO alert last loop
                    battAlertFlag = 0;
                   
                    % return interface to nominal
                    app.LEMDist.Visible = 'on';
                    app.ROIDist.Visible = 'on';
                    app.TaskInstructions.Visible = 'on';
                    app.MiniMap.Position = [504 323 121 112];
                    app.CompassAxes.Position = [460 382 42 42];
                    app.ROIDist.Position = [410 342 98 31];
                    app.LEMDist.Position = [410 356 95 31];

                end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
% DISPLAY DATA
    % Oxygen Data
                % Update oxygen bar
                currentLevel = oxygenData(frameIdx)/100; % Get current oxygen level
                oxygenBar.Position = [0, 0, 1, currentLevel]; % Adjust bar height

                % Check if oxygen level is below 75%
                if currentLevel < 0.75
                 % Double the size of the axes
                 ylim(app.UIOxygenAxes, [0, 2]);
                 oxygenBar.Position = [0, 0, 1, 2 * currentLevel]; % Double the bar height
                else
                 % Reset to normal size
                 ylim(app.UIOxygenAxes, [0, 1]);
                 oxygenBar.Position = [0, 0, 1, currentLevel]; % Normal bar height
                end

% no longer need this stuff below
%                 % Update lamp color and text based on oxygen level
%                 if currentLevel < 0.75  % If oxygen level is below 50%
%                     app.LOWO2Lamp.Visible = "on";
%                     app.LOWO2LampLabel.Visible = "on";
%                     app.LOWO2Lamp.Color = 'red'; % Set lamp color to red for warning
%                     app.LOWO2LampLabel.Text = 'LOW O2';
%                 else
%                     app.LOWO2Lamp.Visible = "off";
%                     app.LOWO2LampLabel.Visible = "off";
% %                     app.LOWO2Lamp.Visible = 'green'; % Set lamp color to green if oxygen is safe
% %                     app.LOWO2LampLabel.Text = "O2 Nominal";
%                 end

% CO2 Data
                % Update CO2 scrubber status (nominal is 1)
                if co2Data(frameIdx) == 0  % If CO2 value is 0
                    app.NominalLabel.FontColor = 'white'; 
                    app.NominalLabel.BackgroundColor = 'red';
                    app.NominalLabel.Text = 'Off-Nominal'; 
                else
                    app.NominalLabel.FontColor = 'green'; 
                    app.NominalLabel.Text = 'Nominal'; 
                    app.NominalLabel.BackgroundColor = 'none';
                end

% Pressure Data
                % Update pressure data
                if pressureData(frameIdx) < 4.2  % If pressure value is low
                    app.PressureValue.FontColor = 'white'; 
                    app.PressureValue.BackgroundColor = 'red';
                    app.PressureValue.Text = pressureData(frameIdx)+ "psi";
                else
                    app.PressureValue.FontColor = 'green'; 
                    app.PressureValue.Text = pressureData(frameIdx)+ "psi"; 
                    app.PressureValue.BackgroundColor = 'none';
                end
                

% Battery Data
                % Update battery data
                if batteryData(frameIdx) < 60  % If battery value is low
                    app.BatteryValue.FontColor = 'white'; 
                    app.BatteryValue.BackgroundColor = 'red';
                    app.BatteryValue.Text = batteryData(frameIdx)+ "%";
                else
                    app.BatteryValue.FontColor = 'green'; 
                    app.BatteryValue.Text = batteryData(frameIdx)+ "%"; 
                    app.BatteryValue.BackgroundColor = 'none';
                end
           

% HR Data
                % Update HR data
                if heartData(frameIdx) < 90 ||  heartData(frameIdx) > 130 % If HR is bad
                    app.HRLamp.Color = 'red'; 
                    app.HRLamp_2Label.Text = "HR: " + heartData(frameIdx)+ "bpm";
                else 
                    app.HRLamp.Color = 'green'; 
                    app.HRLamp_2Label.Text = "HR: " + heartData(frameIdx)+ "bpm";
                end                

% RR Data
                % Update RR data
                if respData(frameIdx) < 12 ||  respData(frameIdx) > 20 % If HR is bad
                    app.RRLamp.Color = 'red'; 
                    app.RRLampLabel.Text = "RR: " + respData(frameIdx)+ "bpm";
                else 
                    app.RRLamp.Color = 'green'; 
                    app.RRLampLabel.Text = "RR: " + respData(frameIdx)+ "bpm";
                end  
                
% O2 Consumption Rate Data
                % Update data
                if o2consumeData(frameIdx) < 0.1-0.015 ||  o2consumeData(frameIdx) > 0.115 % if bad
                    app.O2ConsumptionLamp.Color = 'red'; 
                    app.O2ConsumptionLampLabel.Text = "O2 Use: " + o2consumeData(frameIdx)+ "psi/min";
                else 
                    app.O2ConsumptionLamp.Color = 'green'; 
                    app.O2ConsumptionLampLabel.Text = "O2 Use: " + o2consumeData(frameIdx)+ "psi/min";
                end 
                app.O2ConsumptionLampLabel.Text = "O2 Use: " + o2consumeData(frameIdx)+ "psi/min";

% CogLoad Data
                % Update lamp color
                if cogloadData(frameIdx) == 0  % If cogload is good
                    app.CogLoadLamp.Color = 'green'; 
                    app.CogLoadLampLabel.Text = "Cog Load: Nom";
                else % If cogload is bad
                    app.CogLoadLamp.Color = 'red'; 
                    app.CogLoadLampLabel.Text = "Cog Load: Off-Nom";
                end

% Compass Data
                % Update compass
                % Update the heading pointer dynamically (example: vary angle)
                headingAngle = compassData(frameIdx);
                headingAngle = mod(headingAngle, 360); % Example: Increment heading
                xPointer = [0, cosd(headingAngle)];
                yPointer = [0, sind(headingAngle)];
                compassPointer.XData = xPointer;
                compassPointer.YData = yPointer;

% LEM Distance Data
                % Update data
                app.LEMDist.Text = lemDistData(frameIdx)+ "m to LEM";

% ROI Distance Data
                % Update data
                app.ROIDist.Text = roiDistData(frameIdx)+ "m to ROI 1";


% Task Data
                % Add new task if applicable
                %app.RRLampLabel.Text = respData(frameIdx)+ "brpm";


% Ending Tasks
                % increment frame index
                frameIdx = frameIdx + 1;
                
                % Draw video frame onto axes
                drawnow % this refreshes the UI

            end        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIOxygenAxes = uiaxes(app.UIFigure);
            zlabel(app.UIOxygenAxes, 'Z')
            app.UIOxygenAxes.XTick = [];
            app.UIOxygenAxes.YTick = [0 0.5 1];
            app.UIOxygenAxes.YTickLabel = {'0'; '50%'; '100%'};
            app.CompassAxes = uiaxes(app.UIFigure);
            app.CompassAxes.XTick = [];
            app.CompassAxes.YTick = [];
            app.MiniMap = uiaxes(app.UIFigure);
            app.MiniMap.XTick = [];
            app.MiniMap.YTick = [];
            app.PlaySimulationButton = uibutton(app.UIFigure, 'push');
            app.PlaySimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySimulationButtonPushed, true);
            app.PlaySimulationButton.Text = 'Play Simulation';
            app.CO2Label = uilabel(app.UIFigure);
            app.CO2Label.Text = 'CO2';
            app.NominalLabel = uilabel(app.UIFigure);
            app.NominalLabel.Text = 'Nominal';
            app.OxygenLevelTitle = uilabel(app.UIFigure);
            app.OxygenLevelTitle.Text = 'O2 Level';
            app.SuitLabel = uilabel(app.UIFigure);
            app.SuitLabel.Text = 'Suit';
            app.PressureValue = uilabel(app.UIFigure);
            app.PressureValue.Text = 'XXpsi';
            app.BattLabel = uilabel(app.UIFigure);
            app.BattLabel.Text = 'Batt';
            app.BatteryValue = uilabel(app.UIFigure);
            app.BatteryValue.Text = 'XX%';
            app.TaskInstructions = uilabel(app.UIFigure);
            app.TaskInstructions.Text = {'Proceed to LEM '; 'Gather supplies Proceed to ROI 1'; 'Identify sample'; 'Drill sample'; 'Return to LEM'};
            app.RRLampLabel = uilabel(app.UIFigure);
            app.RRLampLabel.Text = 'RR';
            app.RRLamp = uilamp(app.UIFigure);
            app.HRLamp_2Label = uilabel(app.UIFigure);
            app.HRLamp_2Label.Text = 'HR';
            app.HRLamp = uilamp(app.UIFigure);
            app.CogLoadLampLabel = uilabel(app.UIFigure);
            app.CogLoadLampLabel.Text = 'Cog. Load';
            app.CogLoadLamp = uilamp(app.UIFigure);
            app.LEMDist = uilabel(app.UIFigure);
            app.LEMDist.Text = 'XXm to LEM';
            app.ROIDist = uilabel(app.UIFigure);
            app.ROIDist.Text = 'XXm to ROI1';
            app.O2ConsumptionLampLabel = uilabel(app.UIFigure);
            app.O2ConsumptionLampLabel.Text = 'O2 Consumption';
            app.O2ConsumptionLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HUD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end